classdef DLHealthApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel1                          matlab.ui.container.Panel
        StartNewDayButton               matlab.ui.control.Button
        HiName                          matlab.ui.control.Label
        YourBMIis                       matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        Category                        matlab.ui.control.Label
        YourBMRis                       matlab.ui.control.Label
        Suggestion                      matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        TotalCaloriesBurnedLabel        matlab.ui.control.Label
        ExerciseText                    matlab.ui.control.Label
        FoodText1                       matlab.ui.control.Label
        TotalCaloricIntakeLabel         matlab.ui.control.Label
        FinishtheDayButton              matlab.ui.control.Button
        ExerciseButton                  matlab.ui.control.Button
        FoodButton                      matlab.ui.control.Button
        Panel3                          matlab.ui.container.Panel
        Camera                          matlab.ui.control.Image
        AddButton                       matlab.ui.control.Button
        CaloricIntakeLabel              matlab.ui.control.Label
        ScanButton                      matlab.ui.control.Button
        FoodLabel                       matlab.ui.control.Label
        Panel4                          matlab.ui.container.Panel
        AddButton_2                     matlab.ui.control.Button
        TimeinhoursEditField            matlab.ui.control.NumericEditField
        TimeinhoursEditFieldLabel       matlab.ui.control.Label
        ExerciseListBox                 matlab.ui.control.ListBox
        ExerciseListBoxLabel            matlab.ui.control.Label
        ImageEx                         matlab.ui.control.Image
        WeightkgTextAreaLabel           matlab.ui.control.Label
        WeightkgTextArea                matlab.ui.control.TextArea
        HeightcmTextAreaLabel           matlab.ui.control.Label
        HeightcmTextArea                matlab.ui.control.TextArea
        GenderTextAreaLabel             matlab.ui.control.Label
        GenderTextArea                  matlab.ui.control.TextArea
        NameTextAreaLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AgeTextAreaLabel                matlab.ui.control.Label
        AgeTextArea                     matlab.ui.control.TextArea
        NameTextArea                    matlab.ui.control.TextArea
        CreateyourprofileButton         matlab.ui.control.Button
        Panel5                          matlab.ui.container.Panel
        SuggestionsLabel                matlab.ui.control.Label
        TotalCaloriesLabel              matlab.ui.control.Label
        YourNetCaloriesoftheDayisLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Name 
        Weightkg
        Heightcm
        BMI
        Gaugedata
        Age
        Gender
        BMR

        m;
        img;
        camObj;
        net;
        inputSize;
        newimg;
        CName;
        calorieDatabase;

        Food1;
        calorieCount;
        totalCalories = 0;
        totalCaloriesBurned = 0


        Time;
        CaloriesburnedData;
        selectedExercise;
        caloriesBurned;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CreateyourprofileButton, Panel1
        function CreateyourprofileButtonPushed(app, event)
           
           GreetingMessage = sprintf('Hi %s !',string(app.Name));
           app.HiName.Text = GreetingMessage;
           
           app.BMI = (app.Weightkg) / ((app.Heightcm / 100)^2);
           GreetingMessage2 = sprintf('Your Body Mass Index (BMI) is %s.',num2str(app.BMI));
           app.YourBMIis.Text = GreetingMessage2;
          
           app.Gaugedata = round(app.BMI,1);
           app.Gauge.Value = app.Gaugedata;

           if app.BMI < 18.5
           app.Category.Text = 'UNDERWEIGHT';
           elseif app.BMI < 25
           app.Category.Text = 'NORMAL';
           elseif app.BMI < 30
           app.Category.Text = 'OVERWEIGHT';
           elseif app.BMI <40
           app.Category.Text = 'OBESITY';
           else
           app.Category.Text = 'EXTREME OBESITY';
           end

           app.Category.Position = [162 370 305 31];
           app.Category.FontColor = [1 1 1];

            if strcmpi(app.Gender, 'Male')
            app.BMR = 10*app.Weightkg + 6.25*app.Heightcm - 5*app.Age + 5;
            app.YourBMRis.Text = ['Your Basal Metabolic Rate (BMR) is ', num2str(app.BMR),' kcal/day.'];
            elseif strcmpi(app.Gender, 'Female')
            app.BMR = 10*app.Weightkg + 6.25*app.Heightcm - 5*app.Age - 161;
            app.YourBMRis.Text = ['Your Basal Metabolic Rate (BMR) is ', num2str(app.BMR),' kcal/day.'];
            else
            app.YourBMRis.Text = 'Sorry, unable to estimate your Basal Metabolic Rate (BMR).';
            end


            if app.BMI < 18.5
            app.Suggestion.Text = 'A net calorie surplus of more than 500 kcal per day will allow you to gain more than 0.45 kg of weight per week.';
            elseif app.BMI < 25
            app.Suggestion.Text = 'Keep fit!';
            elseif app.BMI < 30
            app.Suggestion.Text = 'A net calorie deficit of more than 500 kcal per day will allow you to lose more than 0.45 kg of weight per week.';
            elseif app.BMI < 40
            app.Suggestion.Text = 'A net calorie deficit of more than 500 kcal per day will allow you to lose more than 0.45 kg of weight per week.';
            else
            app.Suggestion.Text = 'Warning: Special treatments are required!';
            end

           app.Panel1.Visible = 'on';
           app.WeightkgTextArea.Visible = 'off';
           app.CreateyourprofileButton.Visible = 'off';
           app.HeightcmTextArea.Visible = 'off';
           app.NameTextArea.Visible = 'off';
           app.Image.Visible = 'off';
           app.GenderTextArea.Visible = 'off';
           app.GenderTextAreaLabel.Visible = 'off';
           app.WeightkgTextAreaLabel.Visible = 'off';
           app.HeightcmTextAreaLabel.Visible = 'off';
           app.NameTextAreaLabel.Visible = 'off';
           app.AgeTextAreaLabel.Visible = 'off';
           app.AgeTextArea.Visible = 'off';


    % Loads the trained GoogLeNet model and Sets up Webcam       
    pathToMLAPP = fileparts(mfilename('fullpath'));
    trainedNetPath = fullfile(pathToMLAPP, 'trainedGoogLeNet1.mat'); 
    data = load(trainedNetPath, 'net');
    app.net = data.net;
    app.inputSize = app.net.Layers(1).InputSize;

    app.camObj = webcam;
    app.img = snapshot(app.camObj);        
           
    app.calorieDatabase = struct(...
        'toast', 313, ...
        'diet_coke', 0, ...
        'pizza', 414, ...
        'egg', 155, ...
        'milk', 45, ...
        'steak', 1255, ...
        'juice', 45, ...
        'ham', 102 ...
        );

          
        end

        % Button pushed function: StartNewDayButton
        function StartNewDayButtonPushed(app, event)
            app.Panel1.Visible = 'off';
            app.Panel2.Visible = 'on';

            app.StartNewDayButton.Visible = 'off';      
            app.Suggestion.Visible = 'off';       
            app.Gauge.Visible = 'off';                  
            app.Category.Visible = 'off';              
            app.YourBMRis.Visible = 'off';       
        end

        % Button pushed function: FoodButton
        function FoodButtonPushed(app, event)
            app.Panel2.Visible = 'off';
            app.Panel3.Visible = 'on';
            
            app.FoodButton.Visible = 'off';
            app.ExerciseButton.Visible = 'off';
            app.FinishtheDayButton.Visible = 'off';  
            app.TotalCaloricIntakeLabel.Visible = 'off';
        end

        % Value changed function: NameTextArea
        function NameTextAreaValueChanged(app, event)
            app.Name = app.NameTextArea.Value;
            
        end

        % Value changed function: WeightkgTextArea
        function WeightkgTextAreaValueChanged(app, event)
           WeightStr = app.WeightkgTextArea.Value;
           app.Weightkg = str2double(WeightStr);

        end

        % Value changed function: HeightcmTextArea
        function HeightcmTextAreaValueChanged(app, event)
            HeightStr = app.HeightcmTextArea.Value;
            app.Heightcm = str2double(HeightStr);
        end

        % Value changed function: AgeTextArea
        function AgeTextAreaValueChanged(app, event)
           AgeStr = app.AgeTextArea.Value;
           app.Age = str2double(AgeStr);
            
        end

        % Value changed function: GenderTextArea
        function GenderTextAreaValueChanged(app, event)
            app.Gender = app.GenderTextArea.Value;
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.img = snapshot(app.camObj);
            app.Camera.ImageSource = app.img;

            app.newimg = imresize(app.img,app.inputSize(1:2));
            app.CName = classify(app.net,app.newimg);

            CName0 = char(app.CName);
            CName0_safe = strrep(CName0, ' ', '_');


            app.CaloricIntakeLabel.Text = CName0;


    if isfield(app.calorieDatabase, CName0_safe)
        app.calorieCount = app.calorieDatabase.(CName0_safe);
        feedbackText = sprintf('%s: %.1f calories', CName0, app.calorieCount);
    else
        feedbackText = sprintf('%s: Calorie count not available', CName0);
    end

   
    app.CaloricIntakeLabel.Text = feedbackText;

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
    if ~isempty(app.FoodText1.Text)
        app.FoodText1.Text = [app.FoodText1.Text, newline, app.CaloricIntakeLabel.Text];
    else
        app.FoodText1.Text = app.CaloricIntakeLabel.Text;
    end
    calorieText = app.CaloricIntakeLabel.Text;
    calories = regexp(calorieText, '\d+', 'match'); 
    if ~isempty(calories)
        app.calorieCount = str2double(calories{1}); 
        if isempty(app.totalCalories)
            app.totalCalories = 0; 
        end
        app.totalCalories = app.totalCalories + app.calorieCount;
        app.TotalCaloricIntakeLabel.Text = ['Total Caloric intake: ', num2str(app.totalCalories), ' calories'];
    end
    app.Panel3.Visible = 'off';
    app.Panel2.Visible = 'on';
    app.FoodButton.Visible = 'on';
    app.ExerciseButton.Visible = 'on';
    app.FinishtheDayButton.Visible = 'on';
    app.FoodText1.Visible = 'on';
    app.TotalCaloricIntakeLabel.Visible = 'on'; 
            
            
        end

        % Button pushed function: ExerciseButton
        function ExerciseButtonPushed(app, event)
            app.Panel2.Visible = 'off';
            app.Panel4.Visible = 'on';

           app.selectedExercise = app.ExerciseListBox.Value;
           if strcmp(app.selectedExercise, 'Running')
           app.caloriesBurned = 818 * app.Time;
           elseif strcmp(app.selectedExercise, 'Tennis')
           app.caloriesBurned = 660 * app.Time;
           elseif strcmp(app.selectedExercise, 'Swimming')
           app.caloriesBurned = 655 * app.Time;
           elseif strcmp(app.selectedExercise, 'Spinning')
           app.caloriesBurned = 652 * app.Time;
           elseif strcmp(app.selectedExercise, 'HIIT')
           app.caloriesBurned = 573 * app.Time;
           elseif strcmp(app.selectedExercise, 'Rowing')
           app.caloriesBurned = 571 * app.Time;
           elseif strcmp(app.selectedExercise, 'Boxing')
           app.caloriesBurned = 491* app.Time;
            elseif strcmp(app.selectedExercise, 'Aerobics')
           app.caloriesBurned = 409 * app.Time;
           elseif strcmp(app.selectedExercise, 'Pilates')
           app.caloriesBurned = 286* app.Time;
           elseif strcmp(app.selectedExercise, 'Lifting')
           app.caloriesBurned = 245* app.Time;
           end

        end

        % Value changed function: TimeinhoursEditField
        function TimeinhoursEditFieldValueChanged(app, event)
           TimeStr = app.TimeinhoursEditField.Value;
           app.Time = str2double(TimeStr);
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
    app.selectedExercise = app.ExerciseListBox.Value;
    timeInHours = app.TimeinhoursEditField.Value;
    app.caloriesBurned = 0; 
    switch app.selectedExercise
        case 'Running'
            app.caloriesBurned = 818 * timeInHours;
        case 'Tennis'
            app.caloriesBurned = 660 * timeInHours;
            case 'Swimming'
           app. caloriesBurned = 655 * timeInHours;
            case 'Spinning'
            app.caloriesBurned = 652 * timeInHours;
            case 'HIIT'
            app.caloriesBurned = 573 * timeInHours;
            case 'Rowing'
           app. caloriesBurned = 571* timeInHours;
            case 'Boxing'
           app.caloriesBurned = 491 * timeInHours;
        case 'Aerobics'
            app.caloriesBurned = 409 * timeInHours;
        case 'Pilates'
            app.caloriesBurned = 286 * timeInHours;
            case 'Lifting'
            app.caloriesBurned = 245 * timeInHours;
    end
    app.totalCaloriesBurned = app.totalCaloriesBurned + app.caloriesBurned;
    app.TotalCaloriesBurnedLabel.Text = ['Total Calories Burned: ', num2str(app.totalCaloriesBurned), ' calories'];
    if ~isempty(app.ExerciseText.Text)
        app.ExerciseText.Text = [app.ExerciseText.Text, newline, ...
            sprintf('%s: %.1f calories burned', app.selectedExercise, app.caloriesBurned)];
    else
        app.ExerciseText.Text = sprintf('%s: %.1f calories burned', app.selectedExercise, app.caloriesBurned);
    end
    app.Panel4.Visible = 'off';
    app.Panel2.Visible = 'on';
    app.FoodButton.Visible = 'on';
    app.ExerciseButton.Visible = 'on';
    app.FinishtheDayButton.Visible = 'on';
    app.TotalCaloricIntakeLabel.Visible = 'on';


        end

        % Button pushed function: FinishtheDayButton
        function FinishtheDayButtonPushed(app, event)
    totalNetCalories = app.totalCalories - app.totalCaloriesBurned - app.BMR;
    app.TotalCaloriesLabel.Text = [ num2str(totalNetCalories), ' calories'];
    if totalNetCalories < 0
        app.SuggestionsLabel.Text = 'Antibodies are proteins. Get more food!';
    else
        app.SuggestionsLabel.Text = 'Balance your diet with exercise!';
    end
    app.Panel1.Visible = 'off';
    app.Panel2.Visible = 'off';
    app.Panel3.Visible = 'off';
    app.Panel4.Visible = 'off';
    app.Panel5.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Visible = 'off';
            app.YourNetCaloriesoftheDayisLabel = uilabel(app.Panel5);
            app.YourNetCaloriesoftheDayisLabel.Text = 'Your Net Calories of the Day is';
            app.TotalCaloriesLabel = uilabel(app.Panel5);
            app.TotalCaloriesLabel.Text = 'Total Calories';
            app.SuggestionsLabel = uilabel(app.Panel5);
            app.SuggestionsLabel.Text = 'Suggestions';
            app.CreateyourprofileButton = uibutton(app.UIFigure, 'push');
            app.CreateyourprofileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateyourprofileButtonPushed, true);
            app.CreateyourprofileButton.Text = 'Create your profile';
            app.NameTextArea = uitextarea(app.UIFigure);
            app.NameTextArea.ValueChangedFcn = createCallbackFcn(app, @NameTextAreaValueChanged, true);
            app.AgeTextArea = uitextarea(app.UIFigure);
            app.AgeTextArea.ValueChangedFcn = createCallbackFcn(app, @AgeTextAreaValueChanged, true);
            app.AgeTextAreaLabel = uilabel(app.UIFigure);
            app.AgeTextAreaLabel.Text = 'Age: ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.NameTextAreaLabel = uilabel(app.UIFigure);
            app.NameTextAreaLabel.Text = 'Name: ';
            app.GenderTextArea = uitextarea(app.UIFigure);
            app.GenderTextArea.ValueChangedFcn = createCallbackFcn(app, @GenderTextAreaValueChanged, true);
            app.GenderTextAreaLabel = uilabel(app.UIFigure);
            app.GenderTextAreaLabel.Text = 'Gender: ';
            app.HeightcmTextArea = uitextarea(app.UIFigure);
            app.HeightcmTextArea.ValueChangedFcn = createCallbackFcn(app, @HeightcmTextAreaValueChanged, true);
            app.HeightcmTextAreaLabel = uilabel(app.UIFigure);
            app.HeightcmTextAreaLabel.Text = 'Height (cm): ';
            app.WeightkgTextArea = uitextarea(app.UIFigure);
            app.WeightkgTextArea.ValueChangedFcn = createCallbackFcn(app, @WeightkgTextAreaValueChanged, true);
            app.WeightkgTextAreaLabel = uilabel(app.UIFigure);
            app.WeightkgTextAreaLabel.Text = 'Weight (kg): ';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Visible = 'off';
            app.ImageEx = uiimage(app.Panel4);
            app.ImageEx.ImageSource = fullfile(pathToMLAPP, 'exercise.jpg');
            app.ExerciseListBoxLabel = uilabel(app.Panel4);
            app.ExerciseListBoxLabel.Text = 'Exercise';
            app.ExerciseListBox = uilistbox(app.Panel4);
            app.ExerciseListBox.Items = {'Running', 'Tennis', 'Swimming', 'HIIT', 'Rowing', 'Boxing', 'Aerobics', 'Pilates', 'Lifting'};
            app.ExerciseListBox.Value = 'Running';
            app.TimeinhoursEditFieldLabel = uilabel(app.Panel4);
            app.TimeinhoursEditFieldLabel.Text = 'Time in hours:';
            app.TimeinhoursEditField = uieditfield(app.Panel4, 'numeric');
            app.TimeinhoursEditField.Limits = [0 12];
            app.TimeinhoursEditField.ValueChangedFcn = createCallbackFcn(app, @TimeinhoursEditFieldValueChanged, true);
            app.AddButton_2 = uibutton(app.Panel4, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Visible = 'off';
            app.FoodLabel = uilabel(app.Panel3);
            app.FoodLabel.Text = 'Food';
            app.ScanButton = uibutton(app.Panel3, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.CaloricIntakeLabel = uilabel(app.Panel3);
            app.CaloricIntakeLabel.Text = 'Caloric Intake';
            app.AddButton = uibutton(app.Panel3, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.Camera = uiimage(app.Panel3);
            app.Camera.ImageSource = fullfile(pathToMLAPP, 'camera.jpg');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Visible = 'off';
            app.FoodButton = uibutton(app.Panel2, 'push');
            app.FoodButton.ButtonPushedFcn = createCallbackFcn(app, @FoodButtonPushed, true);
            app.FoodButton.Text = 'Food';
            app.ExerciseButton = uibutton(app.Panel2, 'push');
            app.ExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @ExerciseButtonPushed, true);
            app.ExerciseButton.Text = 'Exercise';
            app.FinishtheDayButton = uibutton(app.Panel2, 'push');
            app.FinishtheDayButton.ButtonPushedFcn = createCallbackFcn(app, @FinishtheDayButtonPushed, true);
            app.FinishtheDayButton.Text = 'Finish the Day!';
            app.TotalCaloricIntakeLabel = uilabel(app.Panel2);
            app.TotalCaloricIntakeLabel.Text = 'Total Caloric Intake:';
            app.FoodText1 = uilabel(app.Panel2);
            app.FoodText1.Text = '';
            app.ExerciseText = uilabel(app.Panel2);
            app.ExerciseText.Text = '';
            app.TotalCaloriesBurnedLabel = uilabel(app.Panel2);
            app.TotalCaloriesBurnedLabel.Text = 'Total Calories Burned:';
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Visible = 'off';
            app.Panel1.ButtonDownFcn = createCallbackFcn(app, @CreateyourprofileButtonPushed, true);
            app.Suggestion = uilabel(app.Panel1);
            app.Suggestion.Text = '';
            app.YourBMRis = uilabel(app.Panel1);
            app.YourBMRis.Text = '';
            app.Category = uilabel(app.Panel1);
            app.Category.Text = '';
            app.Gauge = uigauge(app.Panel1, 'semicircular');
            app.Gauge.Limits = [0 40];
            app.Gauge.MajorTicks = [0 10 20 30 40];
            app.YourBMIis = uilabel(app.Panel1);
            app.YourBMIis.Text = '';
            app.HiName = uilabel(app.Panel1);
            app.HiName.Text = '';
            app.StartNewDayButton = uibutton(app.Panel1, 'push');
            app.StartNewDayButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewDayButtonPushed, true);
            app.StartNewDayButton.Text = 'Start New Day';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLHealthApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end